classdef ExponentaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaAppUIFigure   matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        Exponentaru            matlab.ui.control.Button
        Docs                   matlab.ui.control.Button
        MATLABInRussia         matlab.ui.control.Button
        Hub                    matlab.ui.control.Button
        Ask                    matlab.ui.control.Button
        Help                   matlab.ui.control.Button
        Synch                  matlab.ui.control.Button
        Settings               matlab.ui.control.Button
        NotificationsTab       matlab.ui.container.Tab
        NotificationsPanel     matlab.ui.container.Panel
        Tab_3                  matlab.ui.container.Tab
        MATLABIntro            matlab.ui.control.Button
        SimulinkIntro          matlab.ui.control.Button
        Trainings              matlab.ui.control.Button
        VideoList              matlab.ui.control.ListBox
        OpenVideo              matlab.ui.control.Button
        VideosLabel            matlab.ui.control.Label
        Tab_2                  matlab.ui.container.Tab
        Trial                  matlab.ui.control.Button
        Price                  matlab.ui.control.Button
        Support                matlab.ui.control.Button
        Question               matlab.ui.control.Button
        LicenseLabel           matlab.ui.control.Label
        License                matlab.ui.control.EditField
        Feedback               matlab.ui.control.Button
        ExponentaToolboxLabel  matlab.ui.control.Label
        ExponentaToolboxWeb    matlab.ui.control.Button
        Tab_5                  matlab.ui.container.Tab
        MATLABIntro_2          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Updater % ToolboxUpdater
        Notifier % ExponentaNotifier
        Storage %ExponentaStorage
        PrefStorage %Preferences ExponentaStorage
        Notifications % Snackbar notifications
    end
    
    methods (Access = private)
        
        function AddFavorites(app)
            if ~app.Updater.ext.isfavs()
                app.Updater.ext.addfav('Документация', 'rusdoc', 'icons/icon-mw-documentation.png');
                app.Updater.ext.addfav('Сообщество', 'hub', 'icons/icon-mat-people.png');
                app.Updater.ext.addfav('Exponenta App', 'ExponentaApp', 'icons/icon-my-etmc-exponenta.png');
            end
        end
        
        function LoadVideos(app)
            app.Storage = exponenta.internal.Storage('ext', app.Updater.ext, 'fdir', 'data', 'local', 1);
            app.Storage.load();
            videos = app.Storage.get('videos');
            if ~isempty(videos)
                app.VideoList.Items = videos.name;
                app.VideoList.ItemsData = videos.url;
                app.VideoList.Value = app.VideoList.ItemsData(1);
            end
        end
        
        function DrawNotifications(app, notifier)
            app.UpdateNotificationCount(notifier.Data);
            notifier.showNotifications(app.NotificationsPanel, @app.CheckNotification);
        end
        
        function UpdateNotificationCount(app, data)
            if nargin < 2
                data = app.Notifier.Data;
            end
            if isempty(data)
                num = 0;
            else
                num = nnz(~data.checked);
            end
            if num > 0
                title = "События (" + num + ")";
            else
                title = "События";
            end
            app.TabGroup.Children(2).Title = title;
        end
        
        function CheckNotification(app, sbar, varargin)
            code = sbar.UserData;
            app.Notifier.markChecked(code);
            app.UpdateNotificationCount();
        end
        
        function ShowUpdateNotification(app, isupd)
            if isupd
                uisnackbar(app.ExponentaAppUIFigure,...
                    "Доступна новая версия Exponenta Toolbox" + newline + "Рекомендуем обновиться",...
                    'Theme', 'warning', 'Time', inf,...
                    'Actions', {'Что нового?', {@app.ShowUpdateInfo};'Установить обновление', {@app.InstallUpdate}});
            end
        end
        
        function SynchWithRemote(app, force, delay)
            if force || app.PrefStorage.get('AutoCheckUpdate', [], true)
                app.Updater.isupdate(@app.ShowUpdateNotification, delay);
            end
            loadnotif = force || app.PrefStorage.get('AutoLoadNotifications', [], true);
            app.Notifier = exponenta.internal.Notifier(@app.DrawNotifications, loadnotif, app.PrefStorage.ext);
        end
        
        function ShowSynchButton(app)
            if ~app.PrefStorage.get('AutoCheckUpdate') | ~app.PrefStorage.get('AutoLoadNotifications')
                app.Synch.Visible = 'on';
            else
                app.Synch.Visible = 'off';
            end
        end
        
        function sel = ShowUpdateInfo(app, varargin)
            % Update info
            sum = app.Updater.relsum;
            if string(sum) ~= ""
                msg = sprintf("\nОсновные изменения версии v%s:\n%s", app.Updater.vr, sum);
            else
                msg = sprintf("\nОсновные изменения версии v%s не указаны", app.Updater.vr);
            end
            sel = uiconfirm(app.ExponentaAppUIFigure, msg, 'Доступно обновление',...
                'Options', ["Подробнее","Установить","Отмена"], 'DefaultOption', 'Установить',...
                'CancelOption', 'Отмена', 'Icon', 'info');
            switch sel
                case "Установить"
                    app.InstallUpdate();
                case "Подробнее"
                    app.Updater.webrel();
            end
        end
        
        function InstallUpdate(app, varargin)
            wb = uiprogressdlg(app.ExponentaAppUIFigure, 'Title', 'Обновление', 'Indeterminate', 'on');
            exponenta.internal.update(app.Updater, 2, @() delete(app), @ExponentaApp);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Updater = ExponentaUpdater();
            app.PrefStorage = exponenta.internal.Storage('ext', app.Updater.ext, 'type', 'pref', 'auto', 1);
            app.ShowSynchButton();
            app.LoadVideos();
            app.ExponentaToolboxLabel.Text = "Exponenta Toolbox v" + app.Updater.ext.vc;
            app.License.Value = license;
            app.AddFavorites();
            app.SynchWithRemote(false, 3);
        end

        % Button pushed function: Exponentaru
        function ExponentaruPushed(app, event)
            exponenta.web();
        end

        % Button pushed function: Docs
        function DocsPushed(app, event)
            exponenta.rusdoc();
        end

        % Button pushed function: MATLABInRussia
        function MATLABInRussiaPushed(app, event)
            exponenta.matlabinrussia();
        end

        % Button pushed function: Hub
        function HubButtonPushed(app, event)
            exponenta.hub();
        end

        % Button pushed function: Ask
        function AskButtonPushed(app, event)
            exponenta.ask();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            exponenta.doc();
        end

        % Button pushed function: Support
        function SupportButtonPushed(app, event)
            exponenta.support.help();
        end

        % Button pushed function: MATLABIntro
        function MATLABIntroButtonPushed(app, event)
            exponenta.learn.matlab();
        end

        % Button pushed function: SimulinkIntro
        function SimulinkIntroButtonPushed(app, event)
            exponenta.learn.simulink();
        end

        % Button pushed function: Settings
        function SettingsButtonPushed(app, event)
            isupd = app.PrefStorage.get('AutoCheckUpdate', [], true);
            isload = app.PrefStorage.get('AutoLoadNotifications', [], true);
            [inp, ok] = uiinput(app.ExponentaAppUIFigure, {'Автоматическая проверка обновлений' 'Автоматическая загрузка событий'},...
                {isupd isload}, 'Title', 'Настройки', 'Width', 275, 'CancelText', 'Отмена');
            if ok
                app.PrefStorage.set('AutoCheckUpdate', inp{1});
                app.PrefStorage.set('AutoLoadNotifications', inp{2});
                app.ShowSynchButton();
            end
        end

        % Button pushed function: Trainings
        function TrainingsButtonPushed(app, event)
            exponenta.learn.trainings();
        end

        % Button pushed function: ExponentaToolboxWeb
        function ExponentaToolboxWebButtonPushed(app, event)
            app.Updater.ext.web();
        end

        % Button pushed function: Synch
        function SynchButtonPushed(app, event)
            app.SynchWithRemote(true, 0);
        end

        % Button pushed function: OpenVideo
        function OpenVideoButtonPushed(app, event)
            if ~isempty(app.VideoList.Value)
                web(app.VideoList.Value, '-browser');
            end
        end

        % Button pushed function: MATLABIntro_2
        function MATLABIntro_2ButtonPushed(app, event)
            exponenta.github();
        end

        % Button pushed function: Trial
        function TrialButtonPushed(app, event)
            exponenta.support.trial();
        end

        % Button pushed function: Price
        function PriceButtonPushed(app, event)
            exponenta.support.prices();
        end

        % Button pushed function: Question
        function QuestionButtonPushed(app, event)
            exponenta.contact();
        end

        % Button pushed function: Feedback
        function FeedbackButtonPushed(app, event)
            exponenta.feedback();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaAppUIFigure = uifigure('Visible', 'off');
            app.ExponentaAppUIFigure.Name = 'Exponenta App';
            app.TabGroup = uitabgroup(app.ExponentaAppUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Главная';
            app.Exponentaru = uibutton(app.Tab, 'push');
            app.Exponentaru.ButtonPushedFcn = createCallbackFcn(app, @ExponentaruPushed, true);
            app.Exponentaru.Icon = 'icon-my-etmc-exponenta.png';
            app.Exponentaru.Text = 'ЦИТМ "Экспонента"';
            app.Docs = uibutton(app.Tab, 'push');
            app.Docs.ButtonPushedFcn = createCallbackFcn(app, @DocsPushed, true);
            app.Docs.Icon = 'icon-mw-documentation.png';
            app.Docs.Text = 'Документация на русском';
            app.MATLABInRussia = uibutton(app.Tab, 'push');
            app.MATLABInRussia.ButtonPushedFcn = createCallbackFcn(app, @MATLABInRussiaPushed, true);
            app.MATLABInRussia.Icon = 'icon-fa-youtube-play.png';
            app.MATLABInRussia.Text = 'Видео (MATLABInRussia)';
            app.Hub = uibutton(app.Tab, 'push');
            app.Hub.ButtonPushedFcn = createCallbackFcn(app, @HubButtonPushed, true);
            app.Hub.Icon = 'icon-mat-people.png';
            app.Hub.Text = 'Сообщество';
            app.Ask = uibutton(app.Tab, 'push');
            app.Ask.ButtonPushedFcn = createCallbackFcn(app, @AskButtonPushed, true);
            app.Ask.Icon = 'icon-mw-answers.png';
            app.Ask.Text = 'Спросить';
            app.Help = uibutton(app.Tab, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Icon = 'icon-mat-help-outline.png';
            app.Help.Tooltip = {'Справка'};
            app.Help.Text = '';
            app.Synch = uibutton(app.Tab, 'push');
            app.Synch.ButtonPushedFcn = createCallbackFcn(app, @SynchButtonPushed, true);
            app.Synch.Icon = 'icon-fa-refresh.png';
            app.Synch.Tooltip = {'Синхронизация'; 'с интернетом'};
            app.Synch.Text = '';
            app.Settings = uibutton(app.Tab, 'push');
            app.Settings.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.Settings.Icon = 'icon-mat-settings.png';
            app.Settings.Tooltip = {'Настройки'};
            app.Settings.Text = '';
            app.NotificationsTab = uitab(app.TabGroup);
            app.NotificationsTab.Title = 'События';
            app.NotificationsPanel = uipanel(app.NotificationsTab);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Обучение';
            app.MATLABIntro = uibutton(app.Tab_3, 'push');
            app.MATLABIntro.ButtonPushedFcn = createCallbackFcn(app, @MATLABIntroButtonPushed, true);
            app.MATLABIntro.Icon = 'icon-mw-matlab.png';
            app.MATLABIntro.Text = 'Введение в MATLAB';
            app.SimulinkIntro = uibutton(app.Tab_3, 'push');
            app.SimulinkIntro.ButtonPushedFcn = createCallbackFcn(app, @SimulinkIntroButtonPushed, true);
            app.SimulinkIntro.Icon = 'icon-mw-simulink-alt.png';
            app.SimulinkIntro.Text = 'Введение в Simulink';
            app.Trainings = uibutton(app.Tab_3, 'push');
            app.Trainings.ButtonPushedFcn = createCallbackFcn(app, @TrainingsButtonPushed, true);
            app.Trainings.Icon = 'icon-my-edu-student-hat.png';
            app.Trainings.Text = 'Тренинги';
            app.VideoList = uilistbox(app.Tab_3);
            app.VideoList.Items = {};
            app.VideoList.Value = {};
            app.OpenVideo = uibutton(app.Tab_3, 'push');
            app.OpenVideo.ButtonPushedFcn = createCallbackFcn(app, @OpenVideoButtonPushed, true);
            app.OpenVideo.Icon = 'icon-fa-youtube-play.png';
            app.OpenVideo.Text = 'Открыть';
            app.VideosLabel = uilabel(app.Tab_3);
            app.VideosLabel.Text = 'Дополнительно';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Поддержка';
            app.Trial = uibutton(app.Tab_2, 'push');
            app.Trial.ButtonPushedFcn = createCallbackFcn(app, @TrialButtonPushed, true);
            app.Trial.Icon = 'icon-mat-av-timer.png';
            app.Trial.Text = 'Запросить пробную версию';
            app.Price = uibutton(app.Tab_2, 'push');
            app.Price.ButtonPushedFcn = createCallbackFcn(app, @PriceButtonPushed, true);
            app.Price.Icon = 'icon-mat-attach-money.png';
            app.Price.Text = 'Запросить цены';
            app.Support = uibutton(app.Tab_2, 'push');
            app.Support.ButtonPushedFcn = createCallbackFcn(app, @SupportButtonPushed, true);
            app.Support.Icon = 'icon-mw-support.png';
            app.Support.Text = 'Запросить техподдержку';
            app.Question = uibutton(app.Tab_2, 'push');
            app.Question.ButtonPushedFcn = createCallbackFcn(app, @QuestionButtonPushed, true);
            app.Question.Icon = 'icon-mat-mail-outline.png';
            app.Question.Text = 'Связаться по другому вопросу';
            app.LicenseLabel = uilabel(app.Tab_2);
            app.LicenseLabel.Text = 'При обращении предоставьте номер лицензии:';
            app.License = uieditfield(app.Tab_2, 'text');
            app.License.Editable = 'off';
            app.Feedback = uibutton(app.Tab_2, 'push');
            app.Feedback.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.Feedback.Icon = 'icon-my-etmc-exponenta.png';
            app.Feedback.Text = 'Оставить отзыв об Exponenta Toolbox';
            app.ExponentaToolboxLabel = uilabel(app.Tab_2);
            app.ExponentaToolboxLabel.Text = 'Exponenta Toolbox';
            app.ExponentaToolboxWeb = uibutton(app.Tab_2, 'push');
            app.ExponentaToolboxWeb.ButtonPushedFcn = createCallbackFcn(app, @ExponentaToolboxWebButtonPushed, true);
            app.ExponentaToolboxWeb.Icon = 'icon-fa-github.png';
            app.ExponentaToolboxWeb.Text = '';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Проекты';
            app.MATLABIntro_2 = uibutton(app.Tab_5, 'push');
            app.MATLABIntro_2.ButtonPushedFcn = createCallbackFcn(app, @MATLABIntro_2ButtonPushed, true);
            app.MATLABIntro_2.Icon = 'icon-fa-github.png';
            app.MATLABIntro_2.Text = 'Открытые проекты "Экспоненты"';
            % Show the figure after all components are created
            app.ExponentaAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaAppUIFigure)
        end
    end
end