classdef ExponentaEditorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaEditorUIFigure  matlab.ui.Figure
        NotificationsTable       matlab.ui.control.Table
        MessageTextAreaLabel     matlab.ui.control.Label
        Message                  matlab.ui.control.TextArea
        ThemeDropDownLabel       matlab.ui.control.Label
        Theme                    matlab.ui.control.DropDown
        Checked                  matlab.ui.control.CheckBox
        DueDateDatePickerLabel   matlab.ui.control.Label
        DueDate                  matlab.ui.control.DatePicker
        Add                      matlab.ui.control.Button
        Delete                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Editor % ExponentaEditor
        Notifications % Notifications Reactive Table
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Editor = ExponentaEditor;
            app.Notifications = UI.ReactiveTable(app.Editor, 'Data', 'Data');
            app.Notifications.select([1 1]);
            app.Notifications.bind(app.NotificationsTable);
            app.Notifications.bindVar('message', app.Message);
            app.Notifications.bindVar('theme', app.Theme);
            app.Notifications.bindVar('checked', app.Checked);
            app.Notifications.bindVar('duedate', app.DueDate);
        end

        % Cell selection callback: NotificationsTable
        function NotificationsTableCellSelection(app, event)
            app.Notifications.select(event);
        end

        % Value changed function: Checked, DueDate, Message, Theme
        function MessageValueChanged(app, event)
            app.Notifications.updateVar(event);
        end

        % Display data changed function: NotificationsTable
        function NotificationsTableDisplayDataChanged(app, event)
            app.Notifications.update(event);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            code = app.Editor.genCode();
            duedate = datetime + calmonths(1);
            app.Notifications.newRow('theme', 'warning', 'code', code, 'duedate', duedate);
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            app.Notifications.deleteRow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaEditorUIFigure = uifigure('Visible', 'off');
            app.ExponentaEditorUIFigure.Name = 'Exponenta Editor';
            app.NotificationsTable = uitable(app.ExponentaEditorUIFigure);
            app.NotificationsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''};
            app.NotificationsTable.RowName = {};
            app.NotificationsTable.ColumnEditable = [false true true true true];
            app.NotificationsTable.CellSelectionCallback = createCallbackFcn(app, @NotificationsTableCellSelection, true);
            app.NotificationsTable.DisplayDataChangedFcn = createCallbackFcn(app, @NotificationsTableDisplayDataChanged, true);
            app.MessageTextAreaLabel = uilabel(app.ExponentaEditorUIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.Message = uitextarea(app.ExponentaEditorUIFigure);
            app.Message.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.ThemeDropDownLabel = uilabel(app.ExponentaEditorUIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.Theme = uidropdown(app.ExponentaEditorUIFigure);
            app.Theme.Items = {'dark', 'light', 'success', 'info', 'warning', 'danger'};
            app.Theme.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Theme.Value = 'dark';
            app.Checked = uicheckbox(app.ExponentaEditorUIFigure);
            app.Checked.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Checked.Text = 'Checked';
            app.DueDateDatePickerLabel = uilabel(app.ExponentaEditorUIFigure);
            app.DueDateDatePickerLabel.Text = 'Due Date';
            app.DueDate = uidatepicker(app.ExponentaEditorUIFigure);
            app.DueDate.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Add = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.Delete = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            % Show the figure after all components are created
            app.ExponentaEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaEditorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaEditorUIFigure)
        end
    end
end